classdef DeltaBotSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CenterPanel                     matlab.ui.container.Panel
        MotorarmanglevstimePanel        matlab.ui.container.Panel
        UIAxesArmAngle                  matlab.ui.control.UIAxes
        MotorshaftsanglevstimePanel     matlab.ui.container.Panel
        UIAxesMotAngle                  matlab.ui.control.UIAxes
        RobotWorkspacePanel             matlab.ui.container.Panel
        ShowhideworkspaceButton         matlab.ui.control.StateButton
        defaultviewButton               matlab.ui.control.Button
        OxzviewButton                   matlab.ui.control.Button
        OxyviewButton                   matlab.ui.control.Button
        UIAxesTravel                    matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        BaseplateradiusRBmmEditField    matlab.ui.control.NumericEditField
        BaseplateradiusRBmmEditFieldLabel  matlab.ui.control.Label
        MotorarmheightHmmEditField      matlab.ui.control.NumericEditField
        MotorarmheightHmmEditFieldLabel  matlab.ui.control.Label
        MaxeffectorheightmmEditField    matlab.ui.control.NumericEditField
        MaxeffectorheightmmEditFieldLabel  matlab.ui.control.Label
        SetdimensionsButton             matlab.ui.control.Button
        AnglesfromOxaxisLabel           matlab.ui.control.Label
        MotorarmminangleEditField       matlab.ui.control.NumericEditField
        MotorarmminangleLabel           matlab.ui.control.Label
        MotorarmmaxangleEditField       matlab.ui.control.NumericEditField
        MotormaxangleLabel              matlab.ui.control.Label
        Setbothto1ifthemotordirectlydrivesthemechanismLabel  matlab.ui.control.Label
        DrivegeardiametermmEditField    matlab.ui.control.NumericEditField
        DrivegeardiametermmEditFieldLabel  matlab.ui.control.Label
        MotorgeardiametermmEditField    matlab.ui.control.NumericEditField
        MotorgeardiametermmEditFieldLabel  matlab.ui.control.Label
        EffectorradiusREmmEditField     matlab.ui.control.NumericEditField
        EffectorradiusREmmEditFieldLabel  matlab.ui.control.Label
        EffectorarmlengthremmEditField  matlab.ui.control.NumericEditField
        EffectorarmlengthremmEditFieldLabel  matlab.ui.control.Label
        MotorarmlengthrfmmEditField     matlab.ui.control.NumericEditField
        MotorarmlengthrfmmEditFieldLabel  matlab.ui.control.Label
        WorkspacedimensionsmmLabel      matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        TravelTab                       matlab.ui.container.Tab
        GeneratedLampLabel              matlab.ui.control.Label
        ComputeInverseKinematicsButton  matlab.ui.control.Button
        GeneratedLamp                   matlab.ui.control.Lamp
        SendtraveltoworkspaceButton     matlab.ui.control.Button
        GeneratetravelButton            matlab.ui.control.Button
        PaternposttreatmentPanel        matlab.ui.container.Panel
        SetoffsetButton                 matlab.ui.control.Button
        ZoffsetmmEditField              matlab.ui.control.NumericEditField
        ZoffsetmmEditFieldLabel         matlab.ui.control.Label
        YoffsetmmEditField              matlab.ui.control.NumericEditField
        YoffsetmmEditFieldLabel         matlab.ui.control.Label
        XoffsetmmEditField              matlab.ui.control.NumericEditField
        XoffsetmmEditFieldLabel         matlab.ui.control.Label
        RescaleButton                   matlab.ui.control.Button
        ScaleSpinner                    matlab.ui.control.Spinner
        ScaleSpinnerLabel               matlab.ui.control.Label
        OxyrotationaroundZaxisSpinner   matlab.ui.control.Spinner
        OxyrotationaroundZaxisSpinnerLabel  matlab.ui.control.Label
        RotatetravelpaternButton        matlab.ui.control.Button
        PaterngeneratorPanel            matlab.ui.container.Panel
        NumberofiterationsSpinner       matlab.ui.control.Spinner
        NumberofiterationsSpinnerLabel  matlab.ui.control.Label
        ZaxisoffsetmmEditField          matlab.ui.control.NumericEditField
        ZaxisoffsetLabel                matlab.ui.control.Label
        NumberofpointsSpinner           matlab.ui.control.Spinner
        NumberofpointsSpinnerLabel      matlab.ui.control.Label
        WorkspaceratiooccupationSpinner  matlab.ui.control.Spinner
        WorkspaceratiooccupationLabel   matlab.ui.control.Label
        PaternDropDown                  matlab.ui.control.DropDown
        PaternDropDownLabel             matlab.ui.control.Label
        GCodeTab                        matlab.ui.container.Tab
        GCodePanel                      matlab.ui.container.Panel
        EffectorspeedmmminEditField     matlab.ui.control.NumericEditField
        EffectorspeedmmminEditFieldLabel  matlab.ui.control.Label
        ZorginoffsetmmEditField         matlab.ui.control.NumericEditField
        ZorginoffsetmmEditFieldLabel    matlab.ui.control.Label
        YorginoffsetmmEditField         matlab.ui.control.NumericEditField
        YorginoffsetmmEditFieldLabel    matlab.ui.control.Label
        XorginoffsetmmEditField         matlab.ui.control.NumericEditField
        XorginoffsetmmEditFieldLabel    matlab.ui.control.Label
        DiagramTab                      matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        MachinesetupfileMenu            matlab.ui.container.Menu
        TravelfileMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        MachinesetupMenu                matlab.ui.container.Menu
        TravelMenu                      matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        TraveltoGCodeMenu               matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Travel % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitApp(app)
            %draw the workspace
            DrawWorkspace(app.UIAxesTravel, app.XEditField.Value, app.YEditField.Value, app.MotorarmheightHmmEditField.Value, app.MaxeffectorheightmmEditField.Value, app.ZEditField.Value);
            view(app.UIAxesTravel, 3);
        end

        % Button pushed function: SetdimensionsButton
        function b_setWorkSpace(app, event)
            toDel = findobj(app.UIAxesTravel, 'tag', 'workspace');
            delete(toDel);
            DrawWorkspace(app.UIAxesTravel, app.XEditField.Value, app.YEditField.Value, app.MotorarmheightHmmEditField.Value, app.MaxeffectorheightmmEditField.Value, app.ZEditField.Value);


        end

        % Button pushed function: defaultviewButton
        function defaultviewButtonPushed(app, event)
            view(app.UIAxesTravel, 3);
            zoom(app.UIAxesTravel, 'out');
        end

        % Menu selected function: MachinesetupMenu
        function MachinesetupMenuSelected(app, event)
            %save the machine setup to slected file
            [f,p] = uiputfile({'*.DBot'});
            MySetup(1,1) = app.XEditField.Value;
            MySetup(2,1) = app.YEditField.Value;
            MySetup(3,1) = app.ZEditField.Value;
            MySetup(4,1) = app.MotorarmlengthrfmmEditField.Value;
            MySetup(5,1) = app.EffectorarmlengthremmEditField.Value;
            MySetup(6,1) = app.EffectorradiusREmmEditField.Value;
            MySetup(7,1) = app.MotorgeardiametermmEditField.Value;
            MySetup(8,1) = app.DrivegeardiametermmEditField.Value;
            MySetup(9,1) = app.MotorarmmaxangleEditField.Value;
            MySetup(10,1) = app.MotorarmminangleEditField.Value;
            MySetup(11,1) = app.MaxeffectorheightmmEditField.Value;
            MySetup(12,1) = app.MotorarmheightHmmEditField.Value;
            MySetup(13,1) = app.BaseplateradiusRBmmEditField.Value;

            save(sprintf("%s%s", p,f),"MySetup", '-ascii');
        end

        % Menu selected function: MachinesetupfileMenu
        function MachinesetupfileMenuSelected(app, event)
            %load machine setup file
            [f,p] = uigetfile({'*.DBot'});
            MySetup = load(sprintf("%s%s", p, f));
            app.XEditField.Value = MySetup(1,1);
            app.YEditField.Value = MySetup(2,1);
            app.ZEditField.Value = MySetup(3,1);
            app.MotorarmlengthrfmmEditField.Value = MySetup(4,1);
            app.EffectorarmlengthremmEditField.Value = MySetup(5,1);
            app.EffectorradiusREmmEditField.Value = MySetup(6,1);
            app.MotorgeardiametermmEditField.Value = MySetup(7,1);
            app.DrivegeardiametermmEditField.Value = MySetup(8,1);
            app.MotorarmmaxangleEditField.Value = MySetup(9,1);
            app.MotorarmminangleEditField.Value = MySetup(10,1);
            app.MaxeffectorheightmmEditField.Value = MySetup(11,1);
            app.MotorarmheightHmmEditField.Value = MySetup(12,1);
            app.BaseplateradiusRBmmEditField.Value = MySetup(13,1);
        end

        % Button pushed function: GeneratetravelButton
        function GeneratetravelButtonPushed(app, event)
            
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.MotorarmheightHmmEditField.Value;
            
            app.Travel = []; %clear the matrix content (important)
            nb_of_points = app.NumberofpointsSpinner.Value;
            nb_of_iteration = app.NumberofiterationsSpinner.Value;
            Z_offset = app.ZaxisoffsetmmEditField.Value;
            maxHeight = app.MaxeffectorheightmmEditField.Value;
            Work_ratio = app.WorkspaceratiooccupationSpinner.Value ./ 100;

            switch(app.PaternDropDown.Value)
                case "Line"
                    app.Travel(:,1) = linspace(-X*Work_ratio/2, X*Work_ratio/2, nb_of_points);
                    app.Travel(:,2) = zeros(nb_of_points, 1);
                    app.Travel(:,3) = (Z_offset - Z) .* ones(nb_of_points, 1);
                case "Sine"
                    app.Travel(:,1) = linspace(-X*Work_ratio/2, X*Work_ratio/2, nb_of_points);
                    app.Travel(:,2) = Y/2 * Work_ratio * sin((app.Travel(:,1)/(Y/2))*2*pi*nb_of_iteration);
                    app.Travel(:,3) = (Z_offset - Z) .* ones(nb_of_points, 1);
                case "Ellipse"
                    alpha = linspace(0,2*pi, nb_of_points);
                    app.Travel(:,1) = X/2 * Work_ratio * sin(alpha);
                    app.Travel(:,2) = Y/2 * Work_ratio * cos(alpha);
                    app.Travel(:,3) = (Z_offset - Z) .* ones(nb_of_points, 1);
                    
                case "Helix"
                    app.Travel(:,3) = linspace(-Z + Z_offset, -Z+maxHeight, nb_of_points);
                    app.Travel(:,1) = X/2 * Work_ratio * sin((app.Travel(:,3)/(X/2))*4*pi*nb_of_iteration);
                    app.Travel(:,2) = Y/2 * Work_ratio * cos((app.Travel(:,3)/(Y/2))*4*pi*nb_of_iteration);
            end

            app.Travel = Rotation2D(app.Travel, 90); % align travel to Y axis

            app.GeneratedLamp.Color = 'g';
            app.SendtraveltoworkspaceButton.Enable = 1;
            
        end

        % Button pushed function: SendtraveltoworkspaceButton
        function SendtraveltoworkspaceButtonPushed(app, event)
            PlotTravel(app.UIAxesTravel, app.Travel);
            app.GeneratedLamp.Color = 'r';
            app.SendtraveltoworkspaceButton.Enable = 0;
        end

        % Button pushed function: OxzviewButton
        function OxzviewButtonPushed(app, event)
            zoom(app.UIAxesTravel, 'out');
            view(app.UIAxesTravel, 0,0);
        end

        % Button pushed function: OxyviewButton
        function OxyviewButtonPushed(app, event)
            zoom(app.UIAxesTravel, 'out');
            view(app.UIAxesTravel, 0,90);
        end

        % Value changed function: ShowhideworkspaceButton
        function ShowhideworkspaceButtonValueChanged(app, event)
            value = app.ShowhideworkspaceButton.Value;
            if(value == 1)
                toDel = findobj(app.UIAxesTravel, 'tag', 'workspace');
                delete(toDel);
            else
                DrawWorkspace(app.UIAxesTravel, app.XEditField.Value, app.YEditField.Value, app.MotorarmheightHmmEditField.Value, app.MaxeffectorheightmmEditField.Value);
            end
        end

        % Button pushed function: RotatetravelpaternButton
        function RotatetravelpaternButtonPushed(app, event)
            alpha = app.OxyrotationaroundZaxisSpinner.Value;
            app.Travel = Rotation2D(app.Travel, alpha);
            PlotTravel(app.UIAxesTravel, app.Travel);
        end

        % Menu selected function: TravelMenu
        function TravelMenuSelected(app, event)
            [f,p] = uiputfile({'*.dat'});
            ToSave = app.Travel;
            save(sprintf("%s%s", p,f),"ToSave", '-ascii');
        end

        % Menu selected function: TravelfileMenu
        function TravelfileMenuSelected(app, event)
            [f,p] = uigetfile({'*.dat'});
            app.Travel = load(sprintf("%s%s", p, f));
            PlotTravel(app.UIAxesTravel, app.Travel);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.delete();
        end

        % Menu selected function: TraveltoGCodeMenu
        function TraveltoGCodeMenuSelected(app, event)
            [f,p] = uiputfile({'*.gcode'});

            X_off = app.XorginoffsetmmEditField.Value;
            Y_off = app.YorginoffsetmmEditField.Value;
            Z_off = app.ZorginoffsetmmEditField.Value;
            Speed = app.EffectorspeedmmminEditField.Value;
            
            ExportToGCode(app.Travel, [X_off Y_off Z_off Speed], f, p);
        end

        % Button pushed function: ComputeInverseKinematicsButton
        function ComputeInverseKinematicsButtonPushed(app, event)
            LocalTravel = app.Travel;
            %LocalTravel(:,3) = LocalTravel(:,3) + (app.ZEditField.Value - app.MotorarmheightHmmEditField.Value);
            %if(app.Travel ~= 0)
                n = linspace(1,length(app.Travel), length(app.Travel))';
                [theta1,theta2,theta3] = CalcAngles(LocalTravel, app.BaseplateradiusRBmmEditField.Value, ...
                    app.EffectorradiusREmmEditField.Value, ...
                    app.MotorarmlengthrfmmEditField.Value, ...
                    app.EffectorarmlengthremmEditField.Value);

                angleMin = app.MotorarmminangleEditField.Value;
                angleMax = app.MotorarmmaxangleEditField.Value;

                %calculate gear ratio
                reduction = app.DrivegeardiametermmEditField.Value / app.MotorgeardiametermmEditField.Value;

                PlotAngles(app.UIAxesArmAngle, n,theta1, theta2, theta3, angleMin, angleMax);
                PlotAngles(app.UIAxesMotAngle, n,theta1 .* reduction, ...
                    theta2.* reduction, theta3.* reduction, ...
                    angleMin .* reduction, angleMax .* reduction);


%                 plot(app.UIAxesArmAngle, n, theta1);
%                 hold(app.UIAxesArmAngle, 'on');
%                 plot(app.UIAxesArmAngle, n, theta2);
%                 hold(app.UIAxesArmAngle, 'on');
%                 plot(app.UIAxesArmAngle, n, theta3);
%                 hold(app.UIAxesArmAngle, 'on');
%                 lgd = legend(app.UIAxesArmAngle, 'Motor 1', 'Motor 2', 'Motor 3');
            %end
        end

        % Button pushed function: SetoffsetButton
        function SetoffsetButtonPushed(app, event)
            app.Travel(:,1) = app.Travel(:,1) + app.XoffsetmmEditField.Value;
            app.Travel(:,2) = app.Travel(:,2) + app.YoffsetmmEditField.Value;
            app.Travel(:,3) = app.Travel(:,3) + app.ZoffsetmmEditField.Value;
            PlotTravel(app.UIAxesTravel, app.Travel);
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed(app, event)
            app.Travel(:,1:2) = app.Travel(:,1:2) .* (app.ScaleSpinner.Value / 100.0);
            PlotTravel(app.UIAxesTravel, app.Travel);
        end

        % Callback function
        function ClearButtonPushed(app, event)
            app.TextArea.Value = "";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {693, 693, 693};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {693, 693};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x', 325};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.RobotWorkspacePanel = uipanel(app.CenterPanel);
            app.RobotWorkspacePanel.Title = 'Robot Workspace';
            app.UIAxesTravel = uiaxes(app.RobotWorkspacePanel);
            title(app.UIAxesTravel, 'Robot Workspace and pattern')
            xlabel(app.UIAxesTravel, 'X')
            ylabel(app.UIAxesTravel, 'Y')
            zlabel(app.UIAxesTravel, 'Z')
            app.OxyviewButton = uibutton(app.RobotWorkspacePanel, 'push');
            app.OxyviewButton.ButtonPushedFcn = createCallbackFcn(app, @OxyviewButtonPushed, true);
            app.OxyviewButton.Text = 'Oxy view';
            app.OxzviewButton = uibutton(app.RobotWorkspacePanel, 'push');
            app.OxzviewButton.ButtonPushedFcn = createCallbackFcn(app, @OxzviewButtonPushed, true);
            app.OxzviewButton.Text = 'Oxz view';
            app.defaultviewButton = uibutton(app.RobotWorkspacePanel, 'push');
            app.defaultviewButton.ButtonPushedFcn = createCallbackFcn(app, @defaultviewButtonPushed, true);
            app.defaultviewButton.Text = 'default view';
            app.ShowhideworkspaceButton = uibutton(app.RobotWorkspacePanel, 'state');
            app.ShowhideworkspaceButton.ValueChangedFcn = createCallbackFcn(app, @ShowhideworkspaceButtonValueChanged, true);
            app.ShowhideworkspaceButton.Text = 'Show/hide workspace';
            app.MotorshaftsanglevstimePanel = uipanel(app.CenterPanel);
            app.MotorshaftsanglevstimePanel.Title = 'Motor shaft''s angle vs time';
            app.UIAxesMotAngle = uiaxes(app.MotorshaftsanglevstimePanel);
            title(app.UIAxesMotAngle, 'Motors shaft''s angles vs time')
            xlabel(app.UIAxesMotAngle, 'Sample n')
            ylabel(app.UIAxesMotAngle, 'Angle [°]')
            zlabel(app.UIAxesMotAngle, 'Z')
            app.MotorarmanglevstimePanel = uipanel(app.CenterPanel);
            app.MotorarmanglevstimePanel.Title = 'Motor arm angle vs time';
            app.UIAxesArmAngle = uiaxes(app.MotorarmanglevstimePanel);
            title(app.UIAxesArmAngle, 'Motors arm angles vs sample')
            xlabel(app.UIAxesArmAngle, 'Sample n')
            ylabel(app.UIAxesArmAngle, 'Angle [°]')
            zlabel(app.UIAxesArmAngle, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.XEditFieldLabel = uilabel(app.ParametersTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ParametersTab, 'numeric');
            app.XEditField.Limits = [1 Inf];
            app.XEditField.Value = 290;
            app.YEditFieldLabel = uilabel(app.ParametersTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ParametersTab, 'numeric');
            app.YEditField.Limits = [1 Inf];
            app.YEditField.Value = 290;
            app.ZEditFieldLabel = uilabel(app.ParametersTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ZEditField.Limits = [1 Inf];
            app.ZEditField.Value = 400;
            app.WorkspacedimensionsmmLabel = uilabel(app.ParametersTab);
            app.WorkspacedimensionsmmLabel.Text = 'Workspace dimensions [mm]';
            app.MotorarmlengthrfmmEditFieldLabel = uilabel(app.ParametersTab);
            app.MotorarmlengthrfmmEditFieldLabel.Text = 'Motor arm length (rf)  [mm]';
            app.MotorarmlengthrfmmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MotorarmlengthrfmmEditField.Limits = [1 Inf];
            app.MotorarmlengthrfmmEditField.Value = 90;
            app.EffectorarmlengthremmEditFieldLabel = uilabel(app.ParametersTab);
            app.EffectorarmlengthremmEditFieldLabel.Text = 'Effector arm length (re)  [mm]';
            app.EffectorarmlengthremmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.EffectorarmlengthremmEditField.Limits = [1 Inf];
            app.EffectorarmlengthremmEditField.Value = 230.5;
            app.EffectorradiusREmmEditFieldLabel = uilabel(app.ParametersTab);
            app.EffectorradiusREmmEditFieldLabel.Text = 'Effector radius (RE) [mm]';
            app.EffectorradiusREmmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.EffectorradiusREmmEditField.Value = 50;
            app.MotorgeardiametermmEditFieldLabel = uilabel(app.ParametersTab);
            app.MotorgeardiametermmEditFieldLabel.Text = 'Motor gear diameter [mm]';
            app.MotorgeardiametermmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MotorgeardiametermmEditField.Limits = [0 Inf];
            app.MotorgeardiametermmEditField.Value = 16;
            app.DrivegeardiametermmEditFieldLabel = uilabel(app.ParametersTab);
            app.DrivegeardiametermmEditFieldLabel.Text = 'Drive gear diameter [mm]';
            app.DrivegeardiametermmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DrivegeardiametermmEditField.Limits = [0 Inf];
            app.DrivegeardiametermmEditField.Value = 150;
            app.Setbothto1ifthemotordirectlydrivesthemechanismLabel = uilabel(app.ParametersTab);
            app.Setbothto1ifthemotordirectlydrivesthemechanismLabel.Text = {'Set both to 1 if the motor directly drives the mechanism'; ''};
            app.MotormaxangleLabel = uilabel(app.ParametersTab);
            app.MotormaxangleLabel.Text = 'Motor arm max. angle [°]';
            app.MotorarmmaxangleEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MotorarmmaxangleEditField.Limits = [0 Inf];
            app.MotorarmmaxangleEditField.Value = 50;
            app.MotorarmminangleLabel = uilabel(app.ParametersTab);
            app.MotorarmminangleLabel.Text = 'Motor arm min. angle [°]';
            app.MotorarmminangleEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MotorarmminangleEditField.Value = -70;
            app.AnglesfromOxaxisLabel = uilabel(app.ParametersTab);
            app.AnglesfromOxaxisLabel.Text = 'Angles from Ox axis  ';
            app.SetdimensionsButton = uibutton(app.ParametersTab, 'push');
            app.SetdimensionsButton.ButtonPushedFcn = createCallbackFcn(app, @b_setWorkSpace, true);
            app.SetdimensionsButton.Text = 'Set dimensions';
            app.MaxeffectorheightmmEditFieldLabel = uilabel(app.ParametersTab);
            app.MaxeffectorheightmmEditFieldLabel.Text = 'Max effector height [mm]';
            app.MaxeffectorheightmmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MaxeffectorheightmmEditField.Limits = [1 Inf];
            app.MaxeffectorheightmmEditField.Value = 105;
            app.MotorarmheightHmmEditFieldLabel = uilabel(app.ParametersTab);
            app.MotorarmheightHmmEditFieldLabel.Text = 'Motor arm height (H) [mm]';
            app.MotorarmheightHmmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MotorarmheightHmmEditField.Limits = [1 Inf];
            app.MotorarmheightHmmEditField.Value = 277;
            app.BaseplateradiusRBmmEditFieldLabel = uilabel(app.ParametersTab);
            app.BaseplateradiusRBmmEditFieldLabel.Text = ' Base plate radius (RB)  [mm]';
            app.BaseplateradiusRBmmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BaseplateradiusRBmmEditField.Value = 55;
            app.TravelTab = uitab(app.TabGroup);
            app.TravelTab.Title = 'Travel';
            app.PaterngeneratorPanel = uipanel(app.TravelTab);
            app.PaterngeneratorPanel.Title = 'Patern generator';
            app.PaternDropDownLabel = uilabel(app.PaterngeneratorPanel);
            app.PaternDropDownLabel.Text = 'Patern';
            app.PaternDropDown = uidropdown(app.PaterngeneratorPanel);
            app.PaternDropDown.Items = {'Line', 'Sine', 'Ellipse', 'Helix'};
            app.PaternDropDown.Value = 'Line';
            app.WorkspaceratiooccupationLabel = uilabel(app.PaterngeneratorPanel);
            app.WorkspaceratiooccupationLabel.Text = 'Workspace ratio occupation [%]';
            app.WorkspaceratiooccupationSpinner = uispinner(app.PaterngeneratorPanel);
            app.WorkspaceratiooccupationSpinner.Step = 10;
            app.WorkspaceratiooccupationSpinner.Limits = [10 100];
            app.WorkspaceratiooccupationSpinner.Value = 60;
            app.NumberofpointsSpinnerLabel = uilabel(app.PaterngeneratorPanel);
            app.NumberofpointsSpinnerLabel.Text = 'Number of points';
            app.NumberofpointsSpinner = uispinner(app.PaterngeneratorPanel);
            app.NumberofpointsSpinner.Limits = [3 Inf];
            app.NumberofpointsSpinner.RoundFractionalValues = 'on';
            app.NumberofpointsSpinner.ValueDisplayFormat = '%11g';
            app.NumberofpointsSpinner.Value = 100;
            app.ZaxisoffsetLabel = uilabel(app.PaterngeneratorPanel);
            app.ZaxisoffsetLabel.Text = 'Z axis offset [mm]';
            app.ZaxisoffsetmmEditField = uieditfield(app.PaterngeneratorPanel, 'numeric');
            app.ZaxisoffsetmmEditField.Limits = [0 Inf];
            app.ZaxisoffsetmmEditField.Value = 30;
            app.NumberofiterationsSpinnerLabel = uilabel(app.PaterngeneratorPanel);
            app.NumberofiterationsSpinnerLabel.Text = 'Number of iterations';
            app.NumberofiterationsSpinner = uispinner(app.PaterngeneratorPanel);
            app.NumberofiterationsSpinner.Limits = [1 Inf];
            app.NumberofiterationsSpinner.RoundFractionalValues = 'on';
            app.NumberofiterationsSpinner.ValueDisplayFormat = '%11g';
            app.NumberofiterationsSpinner.Value = 1;
            app.PaternposttreatmentPanel = uipanel(app.TravelTab);
            app.PaternposttreatmentPanel.Title = 'Patern post-treatment';
            app.RotatetravelpaternButton = uibutton(app.PaternposttreatmentPanel, 'push');
            app.RotatetravelpaternButton.ButtonPushedFcn = createCallbackFcn(app, @RotatetravelpaternButtonPushed, true);
            app.RotatetravelpaternButton.Text = 'Rotate travel patern';
            app.OxyrotationaroundZaxisSpinnerLabel = uilabel(app.PaternposttreatmentPanel);
            app.OxyrotationaroundZaxisSpinnerLabel.Text = 'Oxy rotation around Z axis';
            app.OxyrotationaroundZaxisSpinner = uispinner(app.PaternposttreatmentPanel);
            app.OxyrotationaroundZaxisSpinner.Limits = [-180 180];
            app.OxyrotationaroundZaxisSpinner.ValueDisplayFormat = '%+11.4g';
            app.ScaleSpinnerLabel = uilabel(app.PaternposttreatmentPanel);
            app.ScaleSpinnerLabel.Text = 'Scale [%]';
            app.ScaleSpinner = uispinner(app.PaternposttreatmentPanel);
            app.ScaleSpinner.Step = 10;
            app.ScaleSpinner.Limits = [10 Inf];
            app.ScaleSpinner.Value = 70;
            app.RescaleButton = uibutton(app.PaternposttreatmentPanel, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed, true);
            app.RescaleButton.Text = 'Rescale';
            app.XoffsetmmEditFieldLabel = uilabel(app.PaternposttreatmentPanel);
            app.XoffsetmmEditFieldLabel.Text = 'X offset [mm]';
            app.XoffsetmmEditField = uieditfield(app.PaternposttreatmentPanel, 'numeric');
            app.YoffsetmmEditFieldLabel = uilabel(app.PaternposttreatmentPanel);
            app.YoffsetmmEditFieldLabel.Text = 'Y offset [mm]';
            app.YoffsetmmEditField = uieditfield(app.PaternposttreatmentPanel, 'numeric');
            app.ZoffsetmmEditFieldLabel = uilabel(app.PaternposttreatmentPanel);
            app.ZoffsetmmEditFieldLabel.Text = 'Z offset [mm]';
            app.ZoffsetmmEditField = uieditfield(app.PaternposttreatmentPanel, 'numeric');
            app.SetoffsetButton = uibutton(app.PaternposttreatmentPanel, 'push');
            app.SetoffsetButton.ButtonPushedFcn = createCallbackFcn(app, @SetoffsetButtonPushed, true);
            app.SetoffsetButton.Text = 'Set offset';
            app.GeneratetravelButton = uibutton(app.TravelTab, 'push');
            app.GeneratetravelButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratetravelButtonPushed, true);
            app.GeneratetravelButton.Text = 'Generate travel';
            app.SendtraveltoworkspaceButton = uibutton(app.TravelTab, 'push');
            app.SendtraveltoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SendtraveltoworkspaceButtonPushed, true);
            app.SendtraveltoworkspaceButton.Enable = 'off';
            app.SendtraveltoworkspaceButton.Text = {'Send travel to workspace'; ''};
            app.GeneratedLamp = uilamp(app.TravelTab);
            app.ComputeInverseKinematicsButton = uibutton(app.TravelTab, 'push');
            app.ComputeInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeInverseKinematicsButtonPushed, true);
            app.ComputeInverseKinematicsButton.Text = 'Compute Inverse Kinematics';
            app.GeneratedLampLabel = uilabel(app.TravelTab);
            app.GeneratedLampLabel.Text = 'Generated';
            app.GCodeTab = uitab(app.TabGroup);
            app.GCodeTab.Title = 'G-Code';
            app.GCodePanel = uipanel(app.GCodeTab);
            app.GCodePanel.Title = 'G-Code';
            app.XorginoffsetmmEditFieldLabel = uilabel(app.GCodePanel);
            app.XorginoffsetmmEditFieldLabel.Text = 'X orgin offset [mm]';
            app.XorginoffsetmmEditField = uieditfield(app.GCodePanel, 'numeric');
            app.YorginoffsetmmEditFieldLabel = uilabel(app.GCodePanel);
            app.YorginoffsetmmEditFieldLabel.Text = 'Y orgin offset [mm]';
            app.YorginoffsetmmEditField = uieditfield(app.GCodePanel, 'numeric');
            app.ZorginoffsetmmEditFieldLabel = uilabel(app.GCodePanel);
            app.ZorginoffsetmmEditFieldLabel.Text = 'Z orgin offset [mm]';
            app.ZorginoffsetmmEditField = uieditfield(app.GCodePanel, 'numeric');
            app.EffectorspeedmmminEditFieldLabel = uilabel(app.GCodePanel);
            app.EffectorspeedmmminEditFieldLabel.Text = 'Effector speed [mm/min]';
            app.EffectorspeedmmminEditField = uieditfield(app.GCodePanel, 'numeric');
            app.DiagramTab = uitab(app.TabGroup);
            app.DiagramTab.Title = 'Diagram';
            app.Image = uiimage(app.DiagramTab);
            app.Image.ImageSource = 'machine_draw.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open ';
            app.MachinesetupfileMenu = uimenu(app.OpenMenu);
            app.MachinesetupfileMenu.MenuSelectedFcn = createCallbackFcn(app, @MachinesetupfileMenuSelected, true);
            app.MachinesetupfileMenu.Text = 'Machine setup file';
            app.TravelfileMenu = uimenu(app.OpenMenu);
            app.TravelfileMenu.MenuSelectedFcn = createCallbackFcn(app, @TravelfileMenuSelected, true);
            app.TravelfileMenu.Text = 'Travel file';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.MachinesetupMenu = uimenu(app.SaveMenu);
            app.MachinesetupMenu.MenuSelectedFcn = createCallbackFcn(app, @MachinesetupMenuSelected, true);
            app.MachinesetupMenu.Text = 'Machine setup';
            app.TravelMenu = uimenu(app.SaveMenu);
            app.TravelMenu.MenuSelectedFcn = createCallbackFcn(app, @TravelMenuSelected, true);
            app.TravelMenu.Text = 'Travel';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.TraveltoGCodeMenu = uimenu(app.ExportMenu);
            app.TraveltoGCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @TraveltoGCodeMenuSelected, true);
            app.TraveltoGCodeMenu.Text = 'Travel to G-Code';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Workspace and pattern')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaBotSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end